classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MRIAcquisitionSimulatorUIFigure  matlab.ui.Figure
        CompareImageButton             matlab.ui.control.Button
        AcquisitionPanel               matlab.ui.container.Panel
        MethodTextArea                 matlab.ui.control.TextArea
        MethodTextAreaLabel            matlab.ui.control.Label
        PhantomtypeTextArea            matlab.ui.control.TextArea
        PhantomtypeTextAreaLabel       matlab.ui.control.Label
        SamplingLabel                  matlab.ui.control.Label
        outputPointsPerLineEditField   matlab.ui.control.NumericEditField
        ofpointsperlineEditField_2Label  matlab.ui.control.Label
        outputNumLinesEditField        matlab.ui.control.NumericEditField
        oflinesEditField_2Label        matlab.ui.control.Label
        RunAcquisitionButton           matlab.ui.control.Button
        SamplingPanel                  matlab.ui.container.Panel
        ofpointsperlineEditField       matlab.ui.control.NumericEditField
        ofpointsperlineEditFieldLabel  matlab.ui.control.Label
        oflinesEditField               matlab.ui.control.NumericEditField
        oflinesEditFieldLabel          matlab.ui.control.Label
        SamplingButtonGroup            matlab.ui.container.ButtonGroup
        Cartesian                      matlab.ui.control.Image
        Radial                         matlab.ui.control.Image
        CartesianButton                matlab.ui.control.RadioButton
        RadialButton                   matlab.ui.control.RadioButton
        DisplayKSpaceButton            matlab.ui.control.Button
        ReconstructedImagePanel        matlab.ui.container.Panel
        ReconstructedImage             matlab.ui.control.UIAxes
        SampledKSpaceImagePanel        matlab.ui.container.Panel
        SampledKSpaceImage             matlab.ui.control.UIAxes
        KSpaceImagePanel               matlab.ui.container.Panel
        KSpaceImage                    matlab.ui.control.UIAxes
        PhantomImagePanel              matlab.ui.container.Panel
        PhantomImage                   matlab.ui.control.UIAxes
        PhantomPanel                   matlab.ui.container.Panel
        RectangularstructuresizePanel  matlab.ui.container.Panel
        LengthSlider                   matlab.ui.control.Slider
        LengthSliderLabel              matlab.ui.control.Label
        WidthSlider                    matlab.ui.control.Slider
        WidthSliderLabel               matlab.ui.control.Label
        PhantomButtonGroup             matlab.ui.container.ButtonGroup
        Circular                       matlab.ui.control.Image
        Rectangular                    matlab.ui.control.Image
        Phantom2Button                 matlab.ui.control.RadioButton
        Phantom1Button                 matlab.ui.control.RadioButton
        GeneratePhantomButton          matlab.ui.control.Button
        MRIACQUISITIONSIMULATORLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        shape % Description
    end
    
    methods (Access = private)
        
        function [update] = draw(~, canvas, center, radius, color)
            % Get canvas size
            canvas_size = size(canvas);
            
            % Get canvas boundry
            % since canvas is a square, we set boundry to be the value on the x-axis
            boundry = canvas_size(1);
            
            %------------------------------------------------------------------
            % Checking if inner circle is out of scope
            %------------------------------------------------------------------
            
            % The right max value = inner circle x value - radius
            right_max = center(1) - radius;
            
            % The left max value = inner circle x value + radius
            left_max = center(1) + radius;
            
            % The upper max value = inner circle y value + radius
            upper_max = center(2) + radius;
            
            % The lower max value = inner circle y value - radius
            lower_max = center(2) - radius;
            
            % In case inner circle out of scope
            if( right_max < 0 || left_max > boundry || upper_max > boundry || lower_max < 0 )
                update = -1;
            % If everything is correct
            else        
                % the staring point of circle on x-axix
                circle_begin = center(1) - radius;
                % the ending point of circle on y-axix
                circle_end = center(1) + radius;
                
                % loop through each point on the horizontal diameter
                for i  = circle_begin : circle_end
                    current_x = center(1) - i;
                    % If you recall this is the circle function from high school:
                    % x^2 + y^2 = r^2, fun? :)
                    current = floor(sqrt(radius^2 - current_x^2));
                    % measure the length of y at point x
                    y_begin = center(2) - current;
                    y_end = center(2) + current;
                    y_axis = y_begin : y_end;
                    % draw line
                    canvas(i, y_axis) = color;
                end
            end    
            
            % update the canvas
            update = canvas;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GeneratePhantomButton
        function GeneratePhantomButtonPushed(app, event)
            size = [256,256];
            canvas = zeros(size);

            cp = [size(1)/2 , size(2)/2];
            bg_circle_r = ceil(0.4 * size(1));
            canvas = draw(app, canvas, cp, bg_circle_r, 150);
            
            %Change the color to grey 
            canvas = uint8(canvas);

            %[y,x] = find(canvas);
            %plot(app.PhantomImage, x,y);
            
            % specify distance between each pair of inner circles 
            distance = ceil(bg_circle_r/5.4);
            
            if(app.Phantom1Button.Value == 0)
                % make an array of inner circles
                innerCircle = [];
                
                % inner circle 1
                innerCircle(1).center = [cp(1), (cp(2)- floor(4.5 * distance))];
                innerCircle(1).radius = ceil( min(size)/55);
                canvas = draw(app, canvas, innerCircle(1).center, innerCircle(1).radius, 250);
                
                % inner circle 2
                innerCircle(2).center = [cp(1), (cp(2)- 3 * distance)];
                innerCircle(2).radius = floor(1.52 * innerCircle(1).radius);
                canvas = draw(app, canvas, innerCircle(2).center, innerCircle(2).radius, 250);
                
                % inner circle 3
                innerCircle(3).center = [cp(1), (cp(2)- 1 * distance)];
                innerCircle(3).radius = floor(1.52 * innerCircle(2).radius);
                canvas = draw(app, canvas, innerCircle(3).center, innerCircle(3).radius, 250);
                
                % inner circle 4
                innerCircle(4).center = [cp(1), (cp(2)) + distance];
                innerCircle(4).radius = floor(1.52 * innerCircle(3).radius);
                canvas = draw(app, canvas, innerCircle(4).center, innerCircle(4).radius, 232);
                
                % inner circle 5
                innerCircle(5).center = [cp(1), (cp(2) + floor(3.5 * distance))];
                innerCircle(5).radius = floor(1.52 * innerCircle(4).radius);
                canvas = draw(app, canvas, innerCircle(5).center, innerCircle(5).radius, 232);
       
            else 
                width = app.WidthSlider.Value;
                length = app.LengthSlider.Value;
                
                if(width<1)
                    width = 30; 
                end

                if(length < 1)
                    length = 30;
                end

                if(width > 47)
                    width = 47;
                end

                if(length > 47)
                    length = 47;
                end

                r_size = floor([width*3 length*3]);

                % get user input - rectangular size 
                % r_para[width, height]
                r_para = r_size;
        
                % x-axis = height
                % height x-axis starting point
                x1 = floor(cp(2) - r_para(2)/2);
                % height x-axis ending point
                x2 = x1 + r_para(2);
                
                % y-axis = width
                % width y-axix starting point
                y1 = floor(cp(1) - r_para(1)/2);
                % width y-axix ending point
                y2 = y1 + r_para(1);
        
                % draw the square
                canvas(x1 : x2, y1 : y2) = 256;
            end

            imwrite(canvas,'./test.png');


            a = imread('./test.png');
            imshow(a,'parent', app.PhantomImage);
            
        end

        % Button pushed function: DisplayKSpaceButton
        function DisplayKSpaceButtonPushed(app, event)
            % reference: https://www.mathworks.com/help/images/fourier-transform.html
            
            % read the phantom
            imdata = imread('./test.png');
            %figure(1);imshow(imdata); title('Original Image');

            % grey scale
            imdata = im2gray(imdata);
            %figure(2);imshow(imdata); title('Grey Scale');

            % get the fourier transform with zero padding of 1k by 1k
            fourier_t = fft2(imdata, 1280, 1280);

            % shift the k-space
            fourier_shift = fftshift(fourier_t);
            s = abs(fourier_shift);
            %figure(3);imshow(s, []); title('Fourier Transform');


            %TEMPORARY TEST
            targetSize = [512 256];

            win1 = centerCropWindow2d(size(fourier_shift),targetSize);

            B1 = imcrop(abs(log(fourier_shift)), win1);

            %cropped_ft = fourier_shift(250:1006, 250:1006);
            cropped_ft = fourier_shift(1:end, 1:end);
            reconstruction = ifft2(cropped_ft);
            %figure(1);imshow(abs(reconstruction), []); title('Reconstruction 1');

            cropped_ft = fourier_shift(128:1152, 128:1152);
            reconstruction = ifft2(cropped_ft);
            %figure(2);imshow(abs(reconstruction), []); title('Reconstruction 2');

            cropped_ft = fourier_shift(256:1024, 256:1024);
            reconstruction = ifft2(cropped_ft);
            %figure(3);imshow(abs(reconstruction), []); title('Reconstruction 3');

            cropped_ft = fourier_shift(384:896, 384:896);
            reconstruction = ifft2(cropped_ft);
            %figure(4);imshow(abs(reconstruction), []); title('Reconstruction 4');

            cropped_ft = fourier_shift(512:768, 512:768);
            reconstruction = ifft2(cropped_ft);
            %figure(5);imshow(abs(reconstruction), []); title('Reconstruction 5');

            cropped_ft = fourier_shift(576:704, 256:1024);
            reconstruction = ifft2(cropped_ft);
            %figure(6);imshow(abs(reconstruction), []); title('Reconstruction TEST');

            %figure(6);imshow(B1, []); title('Log Transform FT');
            % TEMPORARY END

            % log transform
            s2 = log(s);
            %figure(4);imshow(s2, []); title('Log Transform FT');
           
            % show image in panel
            imshow(s2, [], 'parent', app.KSpaceImage);
        end

        % Button pushed function: RunAcquisitionButton
        function RunAcquisitionButtonPushed(app, event)
            a = app.oflinesEditField.Value;
            b = app.ofpointsperlineEditField.Value;
            [x,y] = meshgrid(-a:b,-a:b);
            z = sqrt(x.^2+y.^2);
            c = (z<15);
            cf = fftshift(fft2(c));
            cf1 = log(1+abs(cf));
            m = max(cf1(:));
            %Generate Sampled K-Space for Radial
            imshow(im2uint8(cf1/m), [], 'parent', app.SampledKSpaceImage);
            o_img = imread('./test.png');
            idiff = 0;
            
            %Reconstructed image using Radial Sampling
            if(app.RadialButton.Value == 1)
                img = imread('./test.png');
                if(a >= 16) && (a < 64)
                    theta1 = 0:10:170;
                    [R1,~] = radon(img,theta1); 
                    num_angles_R1 = size(R1,2)
                    N_R1 = size(R1,1)
                    P_512 = img;
                    [R_512,xp_512] = radon(P_512,theta1);
                    N_512 = size(R_512,1)
                    output_size = max(size(img));
                    dtheta1 = theta1(2) - theta1(1);
                    I1 = iradon(R1,dtheta1,output_size);
                    idiff = I1;
                    %%Generate Sampled K-Space for Radial
                    imshow(I1, [], 'parent', app.ReconstructedImage);

                elseif(a >= 64) && (a <= 128)
                    theta2 = 0:5:175;  
                    [R2,~] = radon(img,theta2);
                    num_angles_R2 = size(R2,2)
                    N_R2 = size(R2,1)
                    P_512 = img;
                    [R_512,xp_512] = radon(P_512,theta2);
                    N_512 = size(R_512,1)
                    output_size = max(size(img));
                    dtheta1 = theta2(2) - theta2(1);
                    I2 = iradon(R2,dtheta1,output_size);
                    idiff = I2;
                    %%Generate K-Space for Radial image
                    imshow(I2, [], 'parent', app.ReconstructedImage);
                else
                    theta3 = 0:2:178;
                    [R3,xp] = radon(img,theta3); 
                    num_angles = size(R3,2)
                    N_R = size(R3,1)
                    P_512 = img;
                    [R_512,xp_512] = radon(P_512,theta3);
                    N_512 = size(R_512,1)
                    output_size = max(size(img));
                    dtheta = theta3(2) - theta3(1);
                    I3 = iradon(R3,dtheta,output_size);
                    idiff = I3;
                    %%Generate Sampled K-Space for Radial image
                    imshow(I3, [], 'parent', app.ReconstructedImage);
                end

            else                
                 % Reconstructed image using Cartesian sampling 
                 num_lines = app.oflinesEditField.Value;
                 num_points = app.ofpointsperlineEditField.Value;

                 x_range = num_lines;
                 y_range = num_points;

                 start_range_x = int64(640 - (num_lines*2.5));
                 start_range_y = int64(640 - (num_points*2.5));

                 % index starts at 1
                 if(start_range_x == 0)
                     start_range_x = 1;
                 end
                 if(start_range_y == 0)
                     start_range_y = 1;
                 end

                 end_range_x = int64(640 + (num_lines*2.5));
                 end_range_y = int64(640 + (num_points*2.5));

                 %disp(start_range_x);
                 %disp(start_range_y);
                 %disp(end_range_x);
                 %disp(end_range_y);

                 % read the phantom
                imdata = imread('./test.png');
                %figure(1);imshow(imdata); title('Original Image');

                % grey scale
                imdata = im2gray(imdata);
                %figure(2);imshow(imdata); title('Grey Scale');

                % get the fourier transform with zero padding of 1k by 1k
                fourier_t = fft2(imdata, 1280, 1280);

                % shift the k-space
                fourier_shift = fftshift(fourier_t);

                cropped_ft = fourier_shift(start_range_x:end_range_x, start_range_y:end_range_y);
                reconstruction = ifft2(cropped_ft);

                %disp(x_range);
                %disp(y_range);

                scaled = reconstruction(1:x_range, 1:y_range);
                abs_scaled = abs(scaled);
                stretchedImage = imresize(abs_scaled, [256 256]);

                target_x = x_range*5;
                target_y = y_range*5;
                targetSize = [target_x target_y];

                 win1 = centerCropWindow2d(size(fourier_shift),targetSize);

                 B1 = imcrop(abs(log(fourier_shift)), win1);
                 B1 = imresize(B1, [256 256]);
                 %Generate Sample K-Space for Cartesian
                 imshow(B1, [], 'parent', app.SampledKSpaceImage);

                %figure(2);imshow(abs(scaled), []); title('Scaled Image');
                %figure(4);imshow(abs(reconstruction), []); title('Reconstructed Image');
                idiff = stretchedImage;
                %Generate Reconstructed image for Cartesian
                imshow(stretchedImage, [], 'parent', app.ReconstructedImage);
            end

            %Reconstruction image panel
            app.PhantomtypeTextArea.Value = app.PhantomButtonGroup.SelectedObject.Text;
            app.MethodTextArea.Value = app.SamplingButtonGroup.SelectedObject.Text;
            numLine = app.oflinesEditField.Value;
            pointsPerLine = app.ofpointsperlineEditField.Value;
            
            app.outputNumLinesEditField.Value = numLine
            app.outputPointsPerLineEditField.Value = pointsPerLine
        end

        % Selection changed function: PhantomButtonGroup
        function PhantomButtonGroupSelectionChanged(app, event)
            switch app.PhantomButtonGroup.SelectedObject.Text
                case 'Phantom1Button'
                    val = 1;
                case 'Phantom2Button'
                    val = 2;
                otherwise
                    val = 0;
            end
        end

        % Selection changed function: SamplingButtonGroup
        function SamplingButtonGroupSelectionChanged(app, event)
            switch app.SamplingButtonGroup.SelectedObject.Text
                case 'RadialButton'
                    val = 1; 
                case 'CartesianButton'
                    val = 2;
                otherwise
                    val = 0;
            end
        end

        % Button pushed function: CompareImageButton
        function CompareImageButtonPushed(app, event)
            a = app.oflinesEditField.Value;
            b = app.ofpointsperlineEditField.Value;
            [x,y] = meshgrid(-a:b,-a:b);
            z = sqrt(x.^2+y.^2);
            c = (z<15);
            cf = fftshift(fft2(c));
            cf1 = log(1+abs(cf));
            m = max(cf1(:));
            %Generate Sampled K-Space for Radial
            imshow(im2uint8(cf1/m), [], 'parent', app.SampledKSpaceImage);
            o_img = imread('./test.png');
            idiff = 0;
            
            %Reconstructed image using Radial Sampling
            if(app.RadialButton.Value == 1)
                img = imread('./test.png');
                if(a >= 16) && (a < 64)
                    theta1 = 0:10:170;
                    [R1,~] = radon(img,theta1); 
                    num_angles_R1 = size(R1,2)
                    N_R1 = size(R1,1)
                    P_512 = img;
                    [R_512,xp_512] = radon(P_512,theta1);
                    N_512 = size(R_512,1)
                    output_size = max(size(img));
                    dtheta1 = theta1(2) - theta1(1);
                    I1 = iradon(R1,dtheta1,output_size);
                    idiff = I1;
                    %%Generate Sampled K-Space for Radial
                    imshow(I1, [], 'parent', app.ReconstructedImage);

                elseif(a >= 64) && (a < 128)
                    theta2 = 0:5:175;  
                    [R2,~] = radon(img,theta2);
                    num_angles_R2 = size(R2,2)
                    N_R2 = size(R2,1)
                    P_512 = img;
                    [R_512,xp_512] = radon(P_512,theta2);
                    N_512 = size(R_512,1)
                    output_size = max(size(img));
                    dtheta1 = theta2(2) - theta2(1);
                    I2 = iradon(R2,dtheta1,output_size);
                    idiff = I2;
                    %%Generate K-Space for Radial image
                    imshow(I2, [], 'parent', app.ReconstructedImage);
                else
                    theta3 = 0:2:178;
                    [R3,xp] = radon(img,theta3); 
                    num_angles = size(R3,2)
                    N_R = size(R3,1)
                    P_512 = img;
                    [R_512,xp_512] = radon(P_512,theta3);
                    N_512 = size(R_512,1)
                    output_size = max(size(img));
                    dtheta = theta3(2) - theta3(1);
                    I3 = iradon(R3,dtheta,output_size);
                    idiff = I3;
                    %%Generate Sampled K-Space for Radial image
                    imshow(I3, [], 'parent', app.ReconstructedImage);
                end

            else                
                 % Reconstructed image using Cartesian sampling 
                 num_lines = app.oflinesEditField.Value;
                 num_points = app.ofpointsperlineEditField.Value;

                 x_range = num_lines;
                 y_range = num_points;

                 start_range_x = int64(640 - (num_lines*2.5));
                 start_range_y = int64(640 - (num_points*2.5));

                 % index starts at 1
                 if(start_range_x == 0)
                     start_range_x = 1;
                 end
                 if(start_range_y == 0)
                     start_range_y = 1;
                 end

                 end_range_x = int64(640 + (num_lines*2.5));
                 end_range_y = int64(640 + (num_points*2.5));

                 %disp(start_range_x);
                 %disp(start_range_y);
                 %disp(end_range_x);
                 %disp(end_range_y);

                 % read the phantom
                imdata = imread('./test.png');
                %figure(1);imshow(imdata); title('Original Image');

                % grey scale
                imdata = im2gray(imdata);
                %figure(2);imshow(imdata); title('Grey Scale');

                % get the fourier transform with zero padding of 1k by 1k
                fourier_t = fft2(imdata, 1280, 1280);

                % shift the k-space
                fourier_shift = fftshift(fourier_t);

                cropped_ft = fourier_shift(start_range_x:end_range_x, start_range_y:end_range_y);
                reconstruction = ifft2(cropped_ft);

                %disp(x_range);
                %disp(y_range);

                scaled = reconstruction(1:x_range, 1:y_range);
                abs_scaled = abs(scaled);
                stretchedImage = imresize(abs_scaled, [256 256]);

                target_x = x_range*5;
                target_y = y_range*5;
                targetSize = [target_x target_y];

                 win1 = centerCropWindow2d(size(fourier_shift),targetSize);

                 B1 = imcrop(abs(log(fourier_shift)), win1);
                 B1 = imresize(B1, [256 256]);
                 %Generate Sample K-Space for Cartesian
                 imshow(B1, [], 'parent', app.SampledKSpaceImage);

                %figure(2);imshow(abs(scaled), []); title('Scaled Image');
                %figure(4);imshow(abs(reconstruction), []); title('Reconstructed Image');
                idiff = stretchedImage;
                %Generate Reconstructed image for Cartesian
                imshow(stretchedImage, [], 'parent', app.ReconstructedImage);
            end

            imshowpair(o_img,idiff,'diff');
            title('Difference Between Phantom and Reconstructed');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MRIAcquisitionSimulatorUIFigure = uifigure('Visible', 'off');
            app.MRIAcquisitionSimulatorUIFigure.Name = 'MRI Acquisition Simulator';
            app.MRIACQUISITIONSIMULATORLabel = uilabel(app.MRIAcquisitionSimulatorUIFigure);
            app.MRIACQUISITIONSIMULATORLabel.Text = 'MRI ACQUISITION SIMULATOR';
            app.GeneratePhantomButton = uibutton(app.MRIAcquisitionSimulatorUIFigure, 'push');
            app.GeneratePhantomButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePhantomButtonPushed, true);
            app.GeneratePhantomButton.Text = 'Generate Phantom';
            app.PhantomPanel = uipanel(app.MRIAcquisitionSimulatorUIFigure);
            app.PhantomPanel.Title = 'Phantom';
            app.PhantomButtonGroup = uibuttongroup(app.PhantomPanel);
            app.PhantomButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PhantomButtonGroupSelectionChanged, true);
            app.Phantom1Button = uiradiobutton(app.PhantomButtonGroup);
            app.Phantom1Button.Text = 'Phantom 1';
            app.Phantom1Button.Value = true;
            app.Phantom2Button = uiradiobutton(app.PhantomButtonGroup);
            app.Phantom2Button.Text = 'Phantom 2';
            app.Rectangular = uiimage(app.PhantomButtonGroup);
            app.Rectangular.ImageSource = 'phantom1.png';
            app.Circular = uiimage(app.PhantomButtonGroup);
            app.Circular.ImageSource = 'phantom2.png';
            app.RectangularstructuresizePanel = uipanel(app.PhantomPanel);
            app.RectangularstructuresizePanel.Title = 'Rectangular structure size';
            app.WidthSliderLabel = uilabel(app.RectangularstructuresizePanel);
            app.WidthSliderLabel.Text = 'Width';
            app.WidthSlider = uislider(app.RectangularstructuresizePanel);
            app.WidthSlider.Limits = [0 50];
            app.WidthSlider.MajorTicks = [0 10 20 30 40 50 60 70 80];
            app.LengthSliderLabel = uilabel(app.RectangularstructuresizePanel);
            app.LengthSliderLabel.Text = 'Length';
            app.LengthSlider = uislider(app.RectangularstructuresizePanel);
            app.LengthSlider.Limits = [0 50];
            app.LengthSlider.MajorTicks = [0 10 20 30 40 50 60 70 80];
            app.PhantomImagePanel = uipanel(app.MRIAcquisitionSimulatorUIFigure);
            app.PhantomImagePanel.Title = 'Phantom image';
            app.PhantomImage = uiaxes(app.PhantomImagePanel);
            zlabel(app.PhantomImage, 'Z')
            app.KSpaceImagePanel = uipanel(app.MRIAcquisitionSimulatorUIFigure);
            app.KSpaceImagePanel.Title = 'K-Space image';
            app.KSpaceImage = uiaxes(app.KSpaceImagePanel);
            zlabel(app.KSpaceImage, 'Z')
            app.SampledKSpaceImagePanel = uipanel(app.MRIAcquisitionSimulatorUIFigure);
            app.SampledKSpaceImagePanel.Title = 'Sampled K-Space image';
            app.SampledKSpaceImage = uiaxes(app.SampledKSpaceImagePanel);
            zlabel(app.SampledKSpaceImage, 'Z')
            app.ReconstructedImagePanel = uipanel(app.MRIAcquisitionSimulatorUIFigure);
            app.ReconstructedImagePanel.Title = 'Reconstructed image';
            app.ReconstructedImage = uiaxes(app.ReconstructedImagePanel);
            zlabel(app.ReconstructedImage, 'Z')
            app.DisplayKSpaceButton = uibutton(app.MRIAcquisitionSimulatorUIFigure, 'push');
            app.DisplayKSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayKSpaceButtonPushed, true);
            app.DisplayKSpaceButton.Text = 'Display K-Space';
            app.SamplingPanel = uipanel(app.MRIAcquisitionSimulatorUIFigure);
            app.SamplingPanel.Title = 'Sampling';
            app.SamplingButtonGroup = uibuttongroup(app.SamplingPanel);
            app.SamplingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SamplingButtonGroupSelectionChanged, true);
            app.RadialButton = uiradiobutton(app.SamplingButtonGroup);
            app.RadialButton.Text = 'Radial';
            app.RadialButton.Value = true;
            app.CartesianButton = uiradiobutton(app.SamplingButtonGroup);
            app.CartesianButton.Text = 'Cartesian';
            app.Radial = uiimage(app.SamplingButtonGroup);
            app.Radial.ImageSource = 'radial.png';
            app.Cartesian = uiimage(app.SamplingButtonGroup);
            app.Cartesian.ImageSource = 'cartesian.png';
            app.oflinesEditFieldLabel = uilabel(app.SamplingPanel);
            app.oflinesEditFieldLabel.Text = '# of lines';
            app.oflinesEditField = uieditfield(app.SamplingPanel, 'numeric');
            app.oflinesEditField.Limits = [16 256];
            app.oflinesEditField.Value = 16;
            app.ofpointsperlineEditFieldLabel = uilabel(app.SamplingPanel);
            app.ofpointsperlineEditFieldLabel.Text = '# of points per line';
            app.ofpointsperlineEditField = uieditfield(app.SamplingPanel, 'numeric');
            app.ofpointsperlineEditField.Limits = [16 256];
            app.ofpointsperlineEditField.Value = 16;
            app.RunAcquisitionButton = uibutton(app.MRIAcquisitionSimulatorUIFigure, 'push');
            app.RunAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @RunAcquisitionButtonPushed, true);
            app.RunAcquisitionButton.Text = 'Run Acquisition';
            app.AcquisitionPanel = uipanel(app.MRIAcquisitionSimulatorUIFigure);
            app.AcquisitionPanel.Title = 'Acquisition';
            app.oflinesEditField_2Label = uilabel(app.AcquisitionPanel);
            app.oflinesEditField_2Label.Text = '# of lines';
            app.outputNumLinesEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.outputNumLinesEditField.Editable = 'off';
            app.ofpointsperlineEditField_2Label = uilabel(app.AcquisitionPanel);
            app.ofpointsperlineEditField_2Label.Text = '# of points per line';
            app.outputPointsPerLineEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.outputPointsPerLineEditField.Editable = 'off';
            app.SamplingLabel = uilabel(app.AcquisitionPanel);
            app.SamplingLabel.Text = 'Sampling';
            app.PhantomtypeTextAreaLabel = uilabel(app.AcquisitionPanel);
            app.PhantomtypeTextAreaLabel.Text = 'Phantom type';
            app.PhantomtypeTextArea = uitextarea(app.AcquisitionPanel);
            app.PhantomtypeTextArea.Editable = 'off';
            app.MethodTextAreaLabel = uilabel(app.AcquisitionPanel);
            app.MethodTextAreaLabel.Text = 'Method';
            app.MethodTextArea = uitextarea(app.AcquisitionPanel);
            app.MethodTextArea.Editable = 'off';
            app.CompareImageButton = uibutton(app.MRIAcquisitionSimulatorUIFigure, 'push');
            app.CompareImageButton.ButtonPushedFcn = createCallbackFcn(app, @CompareImageButtonPushed, true);
            app.CompareImageButton.Text = 'Compare Image';
            % Show the figure after all components are created
            app.MRIAcquisitionSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MRIAcquisitionSimulatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MRIAcquisitionSimulatorUIFigure)
        end
    end
end